classdef Image_smoothing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label          matlab.ui.control.Label
        Label_11       matlab.ui.control.Label
        Label_22       matlab.ui.control.Label
        Label_33       matlab.ui.control.Label
        Label_44       matlab.ui.control.Label
        Noise          matlab.ui.control.DropDown
        Label_2        matlab.ui.control.Label
        Filter         matlab.ui.control.DropDown
        Label_3        matlab.ui.control.Label
        Button_Select  matlab.ui.control.Button
        Button_Noise   matlab.ui.control.Button
        Button_Run     matlab.ui.control.Button
        Button_About   matlab.ui.control.Button
        Button_Quit    matlab.ui.control.Button
        Image_main     matlab.ui.control.Image
        UIAxes_1       matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes_4       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % Description
        Image_Gray
        Image_Noise
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name="图像平滑@20190502134";
            axis(app.UIAxes_1,"off");
            axis(app.UIAxes_2,"off");
            axis(app.UIAxes_3,"off");
            axis(app.UIAxes_4,"off");
        end

        % Button pushed function: Button_Select
        function Button_SelectPushed(app, event)
            [filename,pathname]=uigetfile({'*.jpg' ; '*.bmp ' ; '*.tif';'*.*'});
            if isequal(filename,0)||isequal(pathname,0)
                errordlg("没有选中文件","错误");
            else
                file=strcat(pathname,filename);
                app.Image=imread(file);
                app.Image_main.ImageSource=app.Image;
                app.Image_main.Visible="on";
                app.Button_Noise.Enable="on";

                app.Label_11.Visible="off";
                app.Label_22.Visible="off";
                app.Label_33.Visible="off";
                app.Label_44.Visible="off";
                imshow(close,'Parent',app.UIAxes_1);
                imshow(close,'Parent',app.UIAxes_2);
                imshow(close,'Parent',app.UIAxes_3);
                imshow(close,'Parent',app.UIAxes_4);
                app.Button_Run.Enable="off";
            end

        end

        % Button pushed function: Button_Noise
        function Button_NoisePushed(app, event)
            app.Image_main.Visible="off";
            app.Image_Gray=rgb2gray(app.Image);
            if isequal(app.Noise.Value,'椒盐噪声')
                app.Image_Noise=imnoise(app.Image_Gray,'salt',0.2);
            else
                app.Image_Noise=imnoise(app.Image_Gray,'gaussian',0.2);
            end
            imshow(app.Image_Noise,'Parent',app.UIAxes_1);
            app.Button_Run.Enable="on";
            app.Label_11.Visible="on";
        end

        % Button pushed function: Button_Run
        function Button_RunPushed(app, event)
            %%%%%%%%%%%%%均值滤波%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            n=3;
            template = ones(n);
            [height, width] = size(app.Image_Noise);
            x1 = double(app.Image_Noise);
            x2 = x1;
            for i = 1:height-n+1
                for j = 1:width-n+1
                    c = x1(i:i+n-1,j:j+n-1).*template;
                    s = sum(sum(c));
                    x2(i+(n-1)/2,j+(n-1)/2) = s/(n*n);
                end
            end
            Median = uint8(x2);
            imshow(Median,'Parent',app.UIAxes_2);
            app.Label_22.Visible="on";
            %%%%%%%%%%%%%中值滤波%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            n = 3;
            [height, width] = size(app.Image_Noise);
            x1 = double(app.Image_Noise);
            x2 = x1;
            for i = 1:height-n+1
                for j = 1:width-n+1
                    mb = x1(i:i+n-1,j:j+n-1);
                    mb = mb(:);
                    mm = median(mb);
                    x2(i+(n-1)/2,j+(n-1)/2 ) = mm;
                end
            end
            Mid = uint8(x2);
            imshow(Mid,'Parent',app.UIAxes_3);
            app.Label_33.Visible="on";
            %%%%%%%%%%%%%%%%%%理想低通%巴特沃斯低通%%%%%%%%%%%%%%%%%%%%%%%%
            if isequal(app.Filter.Value,'理想低通')
                s=fftshift(fft2(im2double(app.Image_Noise)));
                [a,b]=size(s);%求二维傅里叶变换后图像大小
                d0=50;        % 将理想低通滤波器的截止频率D0设置为50
                a0=round(a/2);
                b0=round(b/2);
                for i=1:a %双重for循环计算频率点(i,j)与频域中心的距离D(i,j)=sqrt((i-round(a/2)^2+(j-round(b/2)^2))
                    for j=1:b
                        dis=sqrt((i-a0)^2+(j-b0)^2);
                        if dis<=d0      % 根据理想低通滤波器产生公式,当D(i,j)<=D0,置为1
                            h=1;
                        else
                            h=0;        % 根据理想低通滤波器产生公式,当D(i,j)>D0,置为0
                        end
                        s(i,j)=h*s(i,j);% 频域图像乘以滤波器的系数
                    end
                end
                Low=real(ifft2(ifftshift(s)));%转换为时域图像

            else
                s=fftshift(fft2(im2double(app.Image_Noise)));
                [a,b]=size(s);
                n=2;            % 将巴特沃斯低通滤波器的阶数n设置为2
                d0=30;
                a0=round(a/2);
                b0=round(b/2);
                for i=1:a
                    for j=1:b
                        dis=sqrt((i-a0)^2+(j-b0)^2);
                        if dis==0
                            h=0;
                        else
                            h=1/(1+(dis/d0)^(2*n));% 巴特沃斯低通滤波器公式1/(1+[D(i,j)/D0]^2n)
                        end
                        s(i,j)=h*s(i,j);    
                    end
                end
                Low=real(ifft2(ifftshift(s)));
            end
            imshow(Low,'Parent',app.UIAxes_4);
            app.Label_44.Visible="on";
        end

        % Button pushed function: Button_About
        function Button_AboutPushed(app, event)
            msgbox(["张灿豪@20190502134";"制作于2022.11.26"],"关于","help");
        end

        % Button pushed function: Button_Quit
        function Button_QuitPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XLim = [0 512];
            app.UIAxes_4.YLim = [0 512];
            app.UIAxes_4.XTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_4.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XLim = [0 512];
            app.UIAxes_3.YLim = [0 512];
            app.UIAxes_3.XTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_3.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XLim = [0 512];
            app.UIAxes_2.YLim = [0 512];
            app.UIAxes_2.XTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_2.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            app.UIAxes_1.XLim = [0 512];
            app.UIAxes_1.YLim = [0 512];
            app.UIAxes_1.XTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_1.YTickLabel = {''; ''; ''; ''; ''; ''};
            app.UIAxes_1.Visible = 'off';
            app.Image_main = uiimage(app.UIFigure);
            app.Image_main.ImageSource = fullfile(pathToMLAPP, 'lena.jpg');
            app.Button_Quit = uibutton(app.UIFigure, 'push');
            app.Button_Quit.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitPushed, true);
            app.Button_Quit.Text = '退出';
            app.Button_About = uibutton(app.UIFigure, 'push');
            app.Button_About.ButtonPushedFcn = createCallbackFcn(app, @Button_AboutPushed, true);
            app.Button_About.Text = '关于';
            app.Button_Run = uibutton(app.UIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Button_RunPushed, true);
            app.Button_Run.Enable = 'off';
            app.Button_Run.Text = '降噪';
            app.Button_Noise = uibutton(app.UIFigure, 'push');
            app.Button_Noise.ButtonPushedFcn = createCallbackFcn(app, @Button_NoisePushed, true);
            app.Button_Noise.Enable = 'off';
            app.Button_Noise.Text = '加噪';
            app.Button_Select = uibutton(app.UIFigure, 'push');
            app.Button_Select.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectPushed, true);
            app.Button_Select.Text = '选择';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '低通滤波类型';
            app.Filter = uidropdown(app.UIFigure);
            app.Filter.Items = {'理想低通', '巴特沃斯'};
            app.Filter.Value = '理想低通';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '添加噪音类型';
            app.Noise = uidropdown(app.UIFigure);
            app.Noise.Items = {'椒盐噪声', '高斯噪声'};
            app.Noise.Value = '椒盐噪声';
            app.Label_44 = uilabel(app.UIFigure);
            app.Label_44.Visible = 'off';
            app.Label_44.Text = '低通滤波⬆️';
            app.Label_33 = uilabel(app.UIFigure);
            app.Label_33.Visible = 'off';
            app.Label_33.Text = '中值滤波⬆️';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Visible = 'off';
            app.Label_22.Text = '均值滤波⬆️';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Visible = 'off';
            app.Label_11.Text = '噪声图⬆️';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图像平滑';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_smoothing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end